classdef inventoryWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TotalEditField_3       matlab.ui.control.NumericEditField
        TotalEditField_2       matlab.ui.control.NumericEditField
        TotalCompleteUnitsthatcanbebuiltfromcurrentpartsinventoryLabel  matlab.ui.control.Label
        InventoryLabel         matlab.ui.control.Label
        COGUnitEditField       matlab.ui.control.NumericEditField
        COGUnitEditFieldLabel  matlab.ui.control.Label
        TotalEditField         matlab.ui.control.NumericEditField
        TotalEditFieldLabel    matlab.ui.control.Label
        InventoryTable         matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainApp
        inventories
        columnNames
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, colors)
            app.mainApp = caller;
            app.InventoryTable.BackgroundColor = colors('panelColor');
            app.inventories = load("inventoriesData.mat").data;
            app.columnNames = ["Part", "Price/Unit", "Quantity", "Value", "Reorder"];
            app.InventoryTable.ColumnName = app.columnNames;
            disp(app.inventories)
            app.InventoryTable.Data = app.inventories;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.mainApp.ViewInventoryButton.Enable = 'on';
            delete(app);  
        end

        % Callback function: not associated with a component
        function AddCustomersButtonPushed(app, event)
            app.AddCustomersButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.addCustomerWindow = addCustomerWindow(app, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InventoryTable = uitable(app.UIFigure);
            app.InventoryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InventoryTable.RowName = {};
            app.InventoryTable.SelectionType = 'row';
            app.TotalEditFieldLabel = uilabel(app.UIFigure);
            app.TotalEditFieldLabel.Text = 'Total';
            app.TotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalEditField.Editable = 'off';
            app.COGUnitEditFieldLabel = uilabel(app.UIFigure);
            app.COGUnitEditFieldLabel.Text = 'COG/Unit';
            app.COGUnitEditField = uieditfield(app.UIFigure, 'numeric');
            app.COGUnitEditField.Editable = 'off';
            app.InventoryLabel = uilabel(app.UIFigure);
            app.InventoryLabel.Text = 'Inventory';
            app.TotalCompleteUnitsthatcanbebuiltfromcurrentpartsinventoryLabel = uilabel(app.UIFigure);
            app.TotalCompleteUnitsthatcanbebuiltfromcurrentpartsinventoryLabel.Text = {'Total Complete Units that can be'; 'built from current parts inventory'};
            app.TotalEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TotalEditField_2.Editable = 'off';
            app.TotalEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.TotalEditField_3.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inventoryWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end